classdef tank_remote < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptRaspTankRemoteUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        TimestampLabel                matlab.ui.control.Label
        StarboardLamp                 matlab.ui.control.Lamp
        StarboardLampLabel            matlab.ui.control.Label
        CenterLamp                    matlab.ui.control.Lamp
        CenterLampLabel               matlab.ui.control.Label
        PortLamp                      matlab.ui.control.Lamp
        PortLampLabel                 matlab.ui.control.Label
        LineSensorStreamSwitch        matlab.ui.control.Switch
        LineSensorStreamSwitchLabel   matlab.ui.control.Label
        SpeedSlider                   matlab.ui.control.Slider
        SpeedSliderLabel              matlab.ui.control.Label
        ClickthespacebelowtousethearrowkeystodriveLabel  matlab.ui.control.Label
        SavedPositionsPanel           matlab.ui.container.Panel
        OtherServosPanel              matlab.ui.container.Panel
        DisablearmservosButton        matlab.ui.control.Button
        GripperSlider                 matlab.ui.control.Slider
        GripperSliderLabel            matlab.ui.control.Label
        ArmServosasManualInputsPanel  matlab.ui.container.Panel
        GoButton                      matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        Joint3EditField               matlab.ui.control.NumericEditField
        Joint3EditFieldLabel          matlab.ui.control.Label
        Joint2EditField               matlab.ui.control.NumericEditField
        Joint2EditFieldLabel          matlab.ui.control.Label
        Joint1EditField               matlab.ui.control.NumericEditField
        Joint1EditFieldLabel          matlab.ui.control.Label
        ArmServosasSlidersPanel       matlab.ui.container.Panel
        Joint3Slider                  matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        axes_arm                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0, 0, 0]

        link1Patch
        link2Patch
        link3Patch

        link1Vertices
        link2Vertices
        link3Vertices

        mqClient
        is_key_held = false
    end
    
    methods (Access = private)        

        function mqttCallback(app, topic, message)            
            messageCell = jsondecode(message)
            messageType = messageCell.type

            if messageType == "line_sensors"
                messagePayload = messageCell.payload;

                sensorValues = messagePayload.values;
                timestamp = messagePayload.timestamp;

                app.TimestampLabel.Text = timestamp;

                if sensorValues(1) == 1
                    app.PortLamp.Color = "black";
                else
                    app.PortLamp.Color = "white";
                end

                if sensorValues(2) == 1
                    app.CenterLamp.Color = "black";
                else
                    app.CenterLamp.Color = "white";
                end

                if sensorValues(3) == 1
                    app.StarboardLamp.Color = "black";
                else
                    app.StarboardLamp.Color = "white";
                end

            end

        end

        function sendMqttJoints(app)
            message.type = "joints";
            message.payload = app.jointAngles;
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message))
        end

        function sendMqttGripper(app, inches)
            message = sprintf('{"type": "%s", "payload": %f}', "gripper", inches);
            write(app.mqClient, "me435/fisherds/to_pi", message)
        end
         
        function sendMqttArmOff(app)
            write(app.mqClient, "me435/fisherds/to_pi", '{"type": "arm_off"}')
        end

        function sendMqttDrive(app, left_forwards, left_pwm, right_forwards, right_pwm)
            message.type = "drive";
            message.payload = [left_forwards, left_pwm, right_forwards, right_pwm];
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message))
        end

        function sendMqttLineStreaming(app, value)
            message.type = "line_streaming";
            message.payload = value;
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message))
        end

        function updateView(app)
            app.updateSliders()
            app.updateManualInputs()
            app.updateArm()
            app.sendMqttJoints()
        end

        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
        end

        function updateManualInputs(app)
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
        end

        function updateArm(app)
            % Create the A homogeneous transformation matrices for the given jointAngles.
            [A1, A2, A3] = create_AdeeptArm_A_matrices(app.jointAngles);
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');  % app.link1Patch.Vertices = 
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            subscribe(app.mqClient, "me435/fisherds/to_computer", "Callback", @app.mqttCallback)

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')'; % app.link1Patch.Vertices
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm();

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateView();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0, 0, 0];
            app.updateView();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.updateView();
        end

        % Value changing function: GripperSlider
        function GripperSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendMqttGripper(changingValue);
        end

        % Button pushed function: DisablearmservosButton
        function DisablearmservosButtonPushed(app, event)
            app.sendMqttArmOff();
        end

        % Key press function: AdeeptRaspTankRemoteUIFigure
        function AdeeptRaspTankRemoteUIFigureKeyPress(app, event)
            if app.is_key_held
                % fprintf("Ignore the hold\n");
                return
            end
            app.is_key_held = true;
            key = event.Key;
            if key == "uparrow"
%                 fprintf("Send forward\n");
                app.sendMqttDrive(1, app.SpeedSlider.Value, 1, app.SpeedSlider.Value)
            elseif key == "downarrow"
%                 fprintf("Send reverse\n");
                app.sendMqttDrive(-1, app.SpeedSlider.Value, -1, app.SpeedSlider.Value)
            elseif key == "leftarrow"
%                 fprintf("Send left\n");
                app.sendMqttDrive(-1, app.SpeedSlider.Value, 1, app.SpeedSlider.Value)
            elseif key == "rightarrow"
%                 fprintf("Send right\n");
                app.sendMqttDrive(1, app.SpeedSlider.Value, -1, app.SpeedSlider.Value)
            else
                fprintf("Unknown pressed key %s\n", key);
            end
        end

        % Key release function: AdeeptRaspTankRemoteUIFigure
        function AdeeptRaspTankRemoteUIFigureKeyRelease(app, event)
            app.is_key_held = false;
            key = event.Key;
            if key == "uparrow" || key == "downarrow" || key == "leftarrow" || key == "rightarrow"
%                 fprintf("Send stop\n");
                app.sendMqttDrive(0, 0, 0, 0)
            else
                fprintf("Unknown released key %s\n", key);
            end
            
        end

        % Value changed function: LineSensorStreamSwitch
        function LineSensorStreamSwitchValueChanged(app, event)
            value = app.LineSensorStreamSwitch.Value;
            app.sendMqttLineStreaming(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptRaspTankRemoteUIFigure = uifigure('Visible', 'off');
            app.AdeeptRaspTankRemoteUIFigure.Name = 'Adeept RaspTank Remote';
            app.AdeeptRaspTankRemoteUIFigure.KeyPressFcn = createCallbackFcn(app, @AdeeptRaspTankRemoteUIFigureKeyPress, true);
            app.AdeeptRaspTankRemoteUIFigure.KeyReleaseFcn = createCallbackFcn(app, @AdeeptRaspTankRemoteUIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.AdeeptRaspTankRemoteUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.ArmServosasSlidersPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArmServosasSlidersPanel.Title = 'Arm Servos as Sliders';
            app.Joint1SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.ArmServosasManualInputsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArmServosasManualInputsPanel.Title = 'Arm Servos as Manual Inputs';
            app.Joint1EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.ResetButton = uibutton(app.ArmServosasManualInputsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GoButton = uibutton(app.ArmServosasManualInputsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.OtherServosPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.OtherServosPanel.Title = 'Other Servos';
            app.GripperSliderLabel = uilabel(app.OtherServosPanel);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.OtherServosPanel);
            app.GripperSlider.Limits = [0 2];
            app.GripperSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.GripperSlider.ValueChangingFcn = createCallbackFcn(app, @GripperSliderValueChanging, true);
            app.GripperSlider.Value = 0.2;
            app.DisablearmservosButton = uibutton(app.OtherServosPanel, 'push');
            app.DisablearmservosButton.ButtonPushedFcn = createCallbackFcn(app, @DisablearmservosButtonPushed, true);
            app.DisablearmservosButton.Text = 'Disable arm servos';
            app.SavedPositionsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.ClickthespacebelowtousethearrowkeystodriveLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.ClickthespacebelowtousethearrowkeystodriveLabel.Text = 'Click the space below to use the arrow keys to drive:';
            app.SpeedSliderLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.AdeeptRaspTankRemoteUIFigure);
            app.SpeedSlider.Limits = [0 1];
            app.SpeedSlider.Value = 0.6;
            app.Panel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.Panel.Title = 'Panel';
            app.LineSensorStreamSwitchLabel = uilabel(app.Panel);
            app.LineSensorStreamSwitchLabel.Text = 'Line Sensor Stream';
            app.LineSensorStreamSwitch = uiswitch(app.Panel, 'slider');
            app.LineSensorStreamSwitch.ValueChangedFcn = createCallbackFcn(app, @LineSensorStreamSwitchValueChanged, true);
            app.PortLampLabel = uilabel(app.Panel);
            app.PortLampLabel.Text = 'Port';
            app.PortLamp = uilamp(app.Panel);
            app.CenterLampLabel = uilabel(app.Panel);
            app.CenterLampLabel.Text = 'Center';
            app.CenterLamp = uilamp(app.Panel);
            app.StarboardLampLabel = uilabel(app.Panel);
            app.StarboardLampLabel.Text = 'Starboard';
            app.StarboardLamp = uilamp(app.Panel);
            app.TimestampLabel = uilabel(app.Panel);
            app.TimestampLabel.Text = 'Timestamp';
            % Show the figure after all components are created
            app.AdeeptRaspTankRemoteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tank_remote

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptRaspTankRemoteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptRaspTankRemoteUIFigure)
        end
    end
end