classdef gpioApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GPIOMQTTAppUIFigure   matlab.ui.Figure
        CountLabel            matlab.ui.control.Label
        SendLEDsButton        matlab.ui.control.Button
        GreenLEDButtonGroup   matlab.ui.container.ButtonGroup
        GreenOffButton        matlab.ui.control.ToggleButton
        GreenOnButton         matlab.ui.control.ToggleButton
        YellowLEDButtonGroup  matlab.ui.container.ButtonGroup
        YellowOffButton       matlab.ui.control.ToggleButton
        YellowOnButton        matlab.ui.control.ToggleButton
        RedLEDButtonGroup     matlab.ui.container.ButtonGroup
        RedOffButton          matlab.ui.control.ToggleButton
        RedOnButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)            
            messageCell = jsondecode(message)
            messageType = messageCell.type

            if messageType == "reset"
                app.counter = 0;
            end
            
            if messageType == "button"
                app.counter = app.counter + messageCell.payload;
            end

            app.CountLabel.Text = sprintf("Counter = %d", app.counter);

        end

        function sendMqttMessage(app, type, payload)
            message = sprintf('{"type": "%s", "payload": "%s"}', type, payload);
            write(app.mqClient, "me435/fisherds/to_pi", message)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient, "me435/fisherds/to_computer", "Callback", @app.myCallback)

        end

        % Selection changed function: RedLEDButtonGroup
        function RedLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if selectedButton == app.RedOffButton
%                 fprintf("Send red off")
                app.sendMqttMessage("red", "off")
            else
%                 fprintf("Send red on")
                app.sendMqttMessage("red", "on")
            end
        end

        % Selection changed function: YellowLEDButtonGroup
        function YellowLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.YellowLEDButtonGroup.SelectedObject;
            if selectedButton == app.YellowOffButton
                app.sendMqttMessage("yellow", "off")
            else
                app.sendMqttMessage("yellow", "on")
            end
            
        end

        % Selection changed function: GreenLEDButtonGroup
        function GreenLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if selectedButton == app.GreenOffButton
                app.sendMqttMessage("green", "off")
            else
                app.sendMqttMessage("green", "on")
            end
        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
            
            payload_string = "[";
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if selectedButton == app.RedOffButton
                payload_string = payload_string + "0, ";
            else
                payload_string = payload_string + "1, ";
            end

            selectedButton = app.YellowLEDButtonGroup.SelectedObject;
            if selectedButton == app.YellowOffButton
                payload_string = payload_string + "0, ";
            else
                payload_string = payload_string + "1, ";
            end

            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if selectedButton == app.GreenOffButton
                payload_string = payload_string + "0";
            else
                payload_string = payload_string + "1";
            end

            payload_string = payload_string + "]";
            
            
            message = sprintf('{"type": "%s", "payload": %s}', "leds", payload_string);
            write(app.mqClient, "me435/fisherds/to_pi", message)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GPIOMQTTAppUIFigure = uifigure('Visible', 'off');
            app.GPIOMQTTAppUIFigure.Name = 'GPIO MQTT App';
            app.RedLEDButtonGroup = uibuttongroup(app.GPIOMQTTAppUIFigure);
            app.RedLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RedLEDButtonGroupSelectionChanged, true);
            app.RedLEDButtonGroup.Title = 'Red LED';
            app.RedOnButton = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOnButton.Text = 'On';
            app.RedOnButton.Value = true;
            app.RedOffButton = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOffButton.Text = 'Off';
            app.YellowLEDButtonGroup = uibuttongroup(app.GPIOMQTTAppUIFigure);
            app.YellowLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YellowLEDButtonGroupSelectionChanged, true);
            app.YellowLEDButtonGroup.Title = 'Yellow LED';
            app.YellowOnButton = uitogglebutton(app.YellowLEDButtonGroup);
            app.YellowOnButton.Text = 'On';
            app.YellowOnButton.Value = true;
            app.YellowOffButton = uitogglebutton(app.YellowLEDButtonGroup);
            app.YellowOffButton.Text = 'Off';
            app.GreenLEDButtonGroup = uibuttongroup(app.GPIOMQTTAppUIFigure);
            app.GreenLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GreenLEDButtonGroupSelectionChanged, true);
            app.GreenLEDButtonGroup.Title = 'Green LED';
            app.GreenOnButton = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOnButton.Text = 'On';
            app.GreenOnButton.Value = true;
            app.GreenOffButton = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOffButton.Text = 'Off';
            app.SendLEDsButton = uibutton(app.GPIOMQTTAppUIFigure, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            app.CountLabel = uilabel(app.GPIOMQTTAppUIFigure);
            app.CountLabel.Text = 'Count = 0';
            % Show the figure after all components are created
            app.GPIOMQTTAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gpioApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GPIOMQTTAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GPIOMQTTAppUIFigure)
        end
    end
end