classdef mqttChatApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MQTTChatUIFigure       matlab.ui.Figure
        ReceivedText           matlab.ui.control.Label
        SendButton             matlab.ui.control.Button
        payloadField           matlab.ui.control.EditField
        payloadEditFieldLabel  matlab.ui.control.Label
        typeField              matlab.ui.control.EditField
        typeEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)            
            messageCell = jsondecode(message);
            messageType = messageCell.type;
%             messagePayload = messageCell.payload

            % TODO: Put the payload on the screen!
%             app.ReceivedText.Text = messagePayload;
            app.counter = app.counter + 1;
            app.ReceivedText.Text = sprintf("Counter = %d", app.counter);
        end

        function sendMqttMessage(app, type, payload)
            message = sprintf('{"type": "%s", "payload": "%s"}', type, payload);
            write(app.mqClient, "me435/fisherds/to_pi", message)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient, "me435/fisherds/to_computer2", "Callback", @app.myCallback)

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.sendMqttMessage(app.typeField.Value, app.payloadField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MQTTChatUIFigure = uifigure('Visible', 'off');
            app.MQTTChatUIFigure.Name = 'MQTT Chat';
            app.typeEditFieldLabel = uilabel(app.MQTTChatUIFigure);
            app.typeEditFieldLabel.Text = 'type';
            app.typeField = uieditfield(app.MQTTChatUIFigure, 'text');
            app.typeField.Value = 'chat';
            app.payloadEditFieldLabel = uilabel(app.MQTTChatUIFigure);
            app.payloadEditFieldLabel.Text = 'payload';
            app.payloadField = uieditfield(app.MQTTChatUIFigure, 'text');
            app.SendButton = uibutton(app.MQTTChatUIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ReceivedText = uilabel(app.MQTTChatUIFigure);
            app.ReceivedText.Text = 'Most recent message';
            % Show the figure after all components are created
            app.MQTTChatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mqttChatApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MQTTChatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MQTTChatUIFigure)
        end
    end
end