classdef tank_remote < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptRaspTankRemoteUIFigure    matlab.ui.Figure
        RightSlider                     matlab.ui.control.Slider
        RightSliderLabel                matlab.ui.control.Label
        LeftSlider                      matlab.ui.control.Slider
        LeftSliderLabel                 matlab.ui.control.Label
        ModesPanel                      matlab.ui.container.Panel
        StopLineFollowingButton         matlab.ui.control.Button
        StartLineFollowingButton        matlab.ui.control.Button
        DriveuntillineButton            matlab.ui.control.Button
        DriveuntilwallButton            matlab.ui.control.Button
        LineSensorsPanel                matlab.ui.container.Panel
        TimestampLabel                  matlab.ui.control.Label
        SteamingLineSensorsSwitch       matlab.ui.control.Switch
        SteamingLineSensorsSwitchLabel  matlab.ui.control.Label
        StarboardLamp                   matlab.ui.control.Lamp
        StarboardLampLabel              matlab.ui.control.Label
        CenterLamp                      matlab.ui.control.Lamp
        CenterLampLabel                 matlab.ui.control.Label
        PortLamp                        matlab.ui.control.Lamp
        PortLampLabel                   matlab.ui.control.Label
        ClickbelowintheblankareatousedrivekeysLabel  matlab.ui.control.Label
        SavedPositionsPanel             matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        GoButton_4                      matlab.ui.control.Button
        SetButton_3                     matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        GoButton_3                      matlab.ui.control.Button
        SetButton_2                     matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        GoButton_2                      matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        OtherServosPanel                matlab.ui.container.Panel
        GripperSlider                   matlab.ui.control.Slider
        GripperSliderLabel              matlab.ui.control.Label
        ArmServosasManualInputsPanel    matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Joint3EditField                 matlab.ui.control.NumericEditField
        Joint3EditFieldLabel            matlab.ui.control.Label
        Joint2EditField                 matlab.ui.control.NumericEditField
        Joint2EditFieldLabel            matlab.ui.control.Label
        Joint1EditField                 matlab.ui.control.NumericEditField
        Joint1EditFieldLabel            matlab.ui.control.Label
        ArmServosasSlidersPanel         matlab.ui.container.Panel
        Joint3Slider                    matlab.ui.control.Slider
        Joint3SliderLabel               matlab.ui.control.Label
        Joint2Slider                    matlab.ui.control.Slider
        Joint2SliderLabel               matlab.ui.control.Label
        Joint1Slider                    matlab.ui.control.Slider
        Joint1SliderLabel               matlab.ui.control.Label
        axes_arm                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0, 0, 0]

        link1Patch
        link2Patch
        link3Patch

        link1Vertices
        link2Vertices
        link3Vertices

        mqClient
        is_key_held = false;
    end
    
    methods (Access = private)
        
        function mqttCallback(app, topic, message)            
            messageCell = jsondecode(message)
            messageType = messageCell.type
            messagePayload = messageCell.payload
            
            % At present we don't receive anything
            app.TimestampLabel.Text = messagePayload.timestamp;
            if messagePayload.values(1) == 0
                app.StarboardLamp.Color = "white";
            else
                app.StarboardLamp.Color = "black";
            end
            if messagePayload.values(2) == 0
                app.CenterLamp.Color = "white";
            else
                app.CenterLamp.Color = "black";
            end
            if messagePayload.values(3) == 0
                app.PortLamp.Color = "white";
            else
                app.PortLamp.Color = "black";
            end
            
        end

        function sendJointAngles(app)
            message.type = "joints";
            message.payload = app.jointAngles;
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message));
        end

        function sendGripper(app, distance)
            message.type = "gripper";
            message.payload = distance;
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message));
        end

        function sendLineStreamingMode(app, payload_message)
            message.type = "line_sensor_stream";
            message.payload = payload_message;
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message));
        end

        function sendMode(app, payload_message)
            message.type = "mode";
            message.payload = payload_message;
            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message));
        end

        
        function sendMqttDrive(app, left_forwards, left_pwm, right_forwards, right_pwm)
            message.type = "drive";
            message.payload = [left_forwards, left_pwm, right_forwards, right_pwm];

            write(app.mqClient, "me435/fisherds/to_pi", jsonencode(message))
        end


        function updateView(app)
            app.updateSliders()
            app.updateManualInputs()
            app.updateArm()
            app.sendJointAngles()
        end

        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
        end

        function updateManualInputs(app)
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
        end

        function updateArm(app)
            % Create the A homogeneous transformation matrices for the given jointAngles.
            [A1, A2, A3] = create_AdeeptArm_A_matrices(app.jointAngles);
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');  % app.link1Patch.Vertices = 
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')'; % app.link1Patch.Vertices
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm();

            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient, "me435/fisherds/to_computer", "Callback", @app.mqttCallback)
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateView();
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateView();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0, 0, 0];
            app.updateView();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.updateView();
        end

        % Value changing function: GripperSlider
        function GripperSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendGripper(changingValue);
        end

        % Value changed function: SteamingLineSensorsSwitch
        function SteamingLineSensorsSwitchValueChanged(app, event)
            value = app.SteamingLineSensorsSwitch.Value;
            app.sendLineStreamingMode(value);
        end

        % Button pushed function: DriveuntilwallButton
        function DriveuntilwallButtonPushed(app, event)
            app.sendMode("go_until_wall");
        end

        % Button pushed function: DriveuntillineButton
        function DriveuntillineButtonPushed(app, event)
            app.sendMode("go_until_line");
        end

        % Key press function: AdeeptRaspTankRemoteUIFigure
        function AdeeptRaspTankRemoteUIFigureKeyPress(app, event)
            if app.is_key_held
                return
            end
            app.is_key_held = true;
            key = event.Key;
            if key == "uparrow"
%                 fprintf("Send forward\n");
                app.sendMqttDrive(1, app.LeftSlider.Value, 1, app.RightSlider.Value)
            elseif key == "downarrow"
%                 fprintf("Send reverse\n");
                app.sendMqttDrive(-1, app.LeftSlider.Value, -1, app.RightSlider.Value)
            elseif key == "leftarrow"
%                 fprintf("Send left\n");
                app.sendMqttDrive(-1, app.LeftSlider.Value, 1, app.RightSlider.Value)
            elseif key == "rightarrow"
%                 fprintf("Send right\n");
                app.sendMqttDrive(1, app.LeftSlider.Value, -1, app.RightSlider.Value)
            else
                fprintf("Unknown pressed key %s\n", key);
            end
        end

        % Key release function: AdeeptRaspTankRemoteUIFigure
        function AdeeptRaspTankRemoteUIFigureKeyRelease(app, event)
            app.is_key_held = false;
            key = event.Key;
            if key == "uparrow" || key == "downarrow" || key == "leftarrow" || key == "rightarrow"
%                 fprintf("Send stop\n");
                app.sendMqttDrive(0, 0, 0, 0)
            else
                fprintf("Unknown released key %s\n", key);
            end
        end

        % Button pushed function: StartLineFollowingButton
        function StartLineFollowingButtonPushed(app, event)
            app.sendMode("line_follow");
        end

        % Button pushed function: StopLineFollowingButton
        function StopLineFollowingButtonPushed(app, event)
            app.sendMode("stop");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptRaspTankRemoteUIFigure = uifigure('Visible', 'off');
            app.AdeeptRaspTankRemoteUIFigure.Name = 'Adeept RaspTank Remote';
            app.AdeeptRaspTankRemoteUIFigure.KeyPressFcn = createCallbackFcn(app, @AdeeptRaspTankRemoteUIFigureKeyPress, true);
            app.AdeeptRaspTankRemoteUIFigure.KeyReleaseFcn = createCallbackFcn(app, @AdeeptRaspTankRemoteUIFigureKeyRelease, true);
            app.axes_arm = uiaxes(app.AdeeptRaspTankRemoteUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.ArmServosasSlidersPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArmServosasSlidersPanel.Title = 'Arm Servos as Sliders';
            app.Joint1SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.ArmServosasSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ArmServosasSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.ArmServosasManualInputsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ArmServosasManualInputsPanel.Title = 'Arm Servos as Manual Inputs';
            app.Joint1EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.ArmServosasManualInputsPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ArmServosasManualInputsPanel, 'numeric');
            app.ResetButton = uibutton(app.ArmServosasManualInputsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GoButton = uibutton(app.ArmServosasManualInputsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.OtherServosPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.OtherServosPanel.Title = 'Other Servos';
            app.GripperSliderLabel = uilabel(app.OtherServosPanel);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.OtherServosPanel);
            app.GripperSlider.Limits = [0 2];
            app.GripperSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.GripperSlider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2'};
            app.GripperSlider.ValueChangingFcn = createCallbackFcn(app, @GripperSliderValueChanging, true);
            app.SavedPositionsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.SetButton = uibutton(app.SavedPositionsPanel, 'push');
            app.SetButton.Text = 'Set';
            app.GoButton_2 = uibutton(app.SavedPositionsPanel, 'push');
            app.GoButton_2.Text = 'Go';
            app.Label = uilabel(app.SavedPositionsPanel);
            app.Label.Text = '0 0 0 0';
            app.SetButton_2 = uibutton(app.SavedPositionsPanel, 'push');
            app.SetButton_2.Text = 'Set';
            app.GoButton_3 = uibutton(app.SavedPositionsPanel, 'push');
            app.GoButton_3.Text = 'Go';
            app.Label_2 = uilabel(app.SavedPositionsPanel);
            app.Label_2.Text = '0 0 0 0';
            app.SetButton_3 = uibutton(app.SavedPositionsPanel, 'push');
            app.SetButton_3.Text = 'Set';
            app.GoButton_4 = uibutton(app.SavedPositionsPanel, 'push');
            app.GoButton_4.Text = 'Go';
            app.Label_3 = uilabel(app.SavedPositionsPanel);
            app.Label_3.Text = '0 0 0 0';
            app.ClickbelowintheblankareatousedrivekeysLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.ClickbelowintheblankareatousedrivekeysLabel.Text = 'Click below in the blank area to use drive keys:';
            app.LineSensorsPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.LineSensorsPanel.Title = 'Line Sensors';
            app.PortLampLabel = uilabel(app.LineSensorsPanel);
            app.PortLampLabel.Text = 'Port';
            app.PortLamp = uilamp(app.LineSensorsPanel);
            app.CenterLampLabel = uilabel(app.LineSensorsPanel);
            app.CenterLampLabel.Text = 'Center';
            app.CenterLamp = uilamp(app.LineSensorsPanel);
            app.StarboardLampLabel = uilabel(app.LineSensorsPanel);
            app.StarboardLampLabel.Text = 'Starboard';
            app.StarboardLamp = uilamp(app.LineSensorsPanel);
            app.SteamingLineSensorsSwitchLabel = uilabel(app.LineSensorsPanel);
            app.SteamingLineSensorsSwitchLabel.Text = 'Steaming Line Sensors';
            app.SteamingLineSensorsSwitch = uiswitch(app.LineSensorsPanel, 'slider');
            app.SteamingLineSensorsSwitch.ValueChangedFcn = createCallbackFcn(app, @SteamingLineSensorsSwitchValueChanged, true);
            app.TimestampLabel = uilabel(app.LineSensorsPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.ModesPanel = uipanel(app.AdeeptRaspTankRemoteUIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveuntilwallButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntilwallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntilwallButtonPushed, true);
            app.DriveuntilwallButton.Text = 'Drive until wall';
            app.DriveuntillineButton = uibutton(app.ModesPanel, 'push');
            app.DriveuntillineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveuntillineButtonPushed, true);
            app.DriveuntillineButton.Text = 'Drive until line';
            app.StartLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLineFollowingButtonPushed, true);
            app.StartLineFollowingButton.Text = 'Start Line Following';
            app.StopLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLineFollowingButtonPushed, true);
            app.StopLineFollowingButton.Text = 'Stop Line Following';
            app.LeftSliderLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.AdeeptRaspTankRemoteUIFigure);
            app.LeftSlider.Limits = [0 1];
            app.LeftSlider.Value = 0.6;
            app.RightSliderLabel = uilabel(app.AdeeptRaspTankRemoteUIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.AdeeptRaspTankRemoteUIFigure);
            app.RightSlider.Limits = [0 1];
            app.RightSlider.Value = 0.6;
            % Show the figure after all components are created
            app.AdeeptRaspTankRemoteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tank_remote

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptRaspTankRemoteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptRaspTankRemoteUIFigure)
        end
    end
end