classdef GroupChat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MQTTHelperUIFigure  matlab.ui.Figure
        LastMessageLabel    matlab.ui.control.Label
        TypeField           matlab.ui.control.EditField
        PayloadLabel_2      matlab.ui.control.Label
        SendButton          matlab.ui.control.Button
        PayloadField        matlab.ui.control.EditField
        PayloadLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
    end
    
    methods (Access = private)
        
        function mqttSendMessage(app, type, payload)
           
            currentMessage = sprintf('{"type": "%s", "payload": "%s"}', type, payload);
            write(app.mqClient, "my_messages", currentMessage);
        end

        function myCallback(app, topic, message)
            fprintf("Received message: %s\n", message);
            messageCell = jsondecode(message);
            messageType = messageCell.type;
            payload = messageCell.payload

            app.LastMessageLabel.Text = payload;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           clc 
            app.mqClient = mqttclient('tcp://broker.hivemq.com');
            subscribe(app.mqClient, "my_messages", "Callback", @app.myCallback);

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.mqttSendMessage(app.TypeField.Value, app.PayloadField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MQTTHelperUIFigure = uifigure('Visible', 'off');
            app.MQTTHelperUIFigure.Name = 'MQTT Helper';
            app.PayloadLabel = uilabel(app.MQTTHelperUIFigure);
            app.PayloadLabel.Text = 'Payload';
            app.PayloadField = uieditfield(app.MQTTHelperUIFigure, 'text');
            app.SendButton = uibutton(app.MQTTHelperUIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.PayloadLabel_2 = uilabel(app.MQTTHelperUIFigure);
            app.PayloadLabel_2.Text = 'Type';
            app.TypeField = uieditfield(app.MQTTHelperUIFigure, 'text');
            app.TypeField.Value = 'chat';
            app.LastMessageLabel = uilabel(app.MQTTHelperUIFigure);
            app.LastMessageLabel.Text = 'Last Message';
            % Show the figure after all components are created
            app.MQTTHelperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupChat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MQTTHelperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MQTTHelperUIFigure)
        end
    end
end