classdef gpioMQTT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button                matlab.ui.control.Button
        CountLabel            matlab.ui.control.Label
        GreenLEDButtonGroup   matlab.ui.container.ButtonGroup
        GreenOffButton        matlab.ui.control.ToggleButton
        GreenOnButton         matlab.ui.control.ToggleButton
        YellowLEDButtonGroup  matlab.ui.container.ButtonGroup
        YellowOffButton       matlab.ui.control.ToggleButton
        YellowOnButton        matlab.ui.control.ToggleButton
        RedLEDButtonGroup     matlab.ui.container.ButtonGroup
        RedOffButton          matlab.ui.control.ToggleButton
        RedOnButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        mqClient
        buttonCounter = 0
    end
    
    methods (Access = private)
        
        function mqttSendMessage(app, type, payload)           
            currentMessage = sprintf('{"type": "%s", "payload": "%s"}', type, payload);
            write(app.mqClient, "fisherds/to_pi", currentMessage);
        end

        function myCallback(app, topic, message)
            fprintf("Received message: %s\n", message);
            messageCell = jsondecode(message);
            messageType = messageCell.type;
%             payload = messageCell.payload
            
            if messageType == "button"
                app.buttonCounter = app.buttonCounter + 1;
                app.CountLabel.Text = sprintf("Counter = %d", app.buttonCounter);
            else
                fprintf("Unknown message: %s", message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc 
            app.mqClient = mqttclient('tcp://broker.hivemq.com');
            subscribe(app.mqClient, "fisherds/to_computer", "Callback", @app.myCallback);
        end

        % Callback function
        function RedLEDButtonGroupButtonDown(app, event)
     
        end

        % Callback function
        function YellowLEDButtonGroupButtonDown(app, event)
   
        end

        % Selection changed function: RedLEDButtonGroup
        function RedLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.RedLEDButtonGroup.SelectedObject;
            if selectedButton == app.RedOnButton
                app.mqttSendMessage("red", "on")
            end
            if selectedButton == app.RedOffButton
                app.mqttSendMessage("red", "off")
            end
        end

        % Selection changed function: YellowLEDButtonGroup
        function YellowLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.YellowLEDButtonGroup.SelectedObject;
            if selectedButton == app.YellowOnButton
                app.mqttSendMessage("yellow", "on")
            end
            if selectedButton == app.YellowOffButton
                app.mqttSendMessage("yellow", "off")
            end
        end

        % Selection changed function: GreenLEDButtonGroup
        function GreenLEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.GreenLEDButtonGroup.SelectedObject;
            if selectedButton == app.GreenOnButton
                app.mqttSendMessage("green", "on")
            end
            if selectedButton == app.GreenOffButton
                app.mqttSendMessage("green", "off")
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.RedLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RedLEDButtonGroupSelectionChanged, true);
            app.RedLEDButtonGroup.Title = 'Red LED';
            app.RedOnButton = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOnButton.Text = 'On';
            app.RedOnButton.Value = true;
            app.RedOffButton = uitogglebutton(app.RedLEDButtonGroup);
            app.RedOffButton.Text = 'Off';
            app.YellowLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.YellowLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YellowLEDButtonGroupSelectionChanged, true);
            app.YellowLEDButtonGroup.Title = 'Yellow LED';
            app.YellowOnButton = uitogglebutton(app.YellowLEDButtonGroup);
            app.YellowOnButton.Text = 'On';
            app.YellowOnButton.Value = true;
            app.YellowOffButton = uitogglebutton(app.YellowLEDButtonGroup);
            app.YellowOffButton.Text = 'Off';
            app.GreenLEDButtonGroup = uibuttongroup(app.UIFigure);
            app.GreenLEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GreenLEDButtonGroupSelectionChanged, true);
            app.GreenLEDButtonGroup.Title = 'Green LED';
            app.GreenOnButton = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOnButton.Text = 'On';
            app.GreenOnButton.Value = true;
            app.GreenOffButton = uitogglebutton(app.GreenLEDButtonGroup);
            app.GreenOffButton.Text = 'Off';
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = 'Count = 0';
            app.Button = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gpioMQTT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end